classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TCOR           matlab.ui.control.Knob
        TSLabel        matlab.ui.control.Label
        OCOR           matlab.ui.control.Knob
        OSLabel        matlab.ui.control.Label
        TYSlider       matlab.ui.control.Slider
        TYSliderLabel  matlab.ui.control.Label
        TXSlider       matlab.ui.control.Slider
        TXSliderLabel  matlab.ui.control.Label
        OYSlider       matlab.ui.control.Slider
        OYSliderLabel  matlab.ui.control.Label
        OXSlider       matlab.ui.control.Slider
        OXSliderLabel  matlab.ui.control.Label
        turning        matlab.ui.control.Label
        duty           matlab.ui.control.Label
        meetingState   matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        inputButton    matlab.ui.control.Button
        runButton      matlab.ui.control.Button
        UITable        matlab.ui.control.Table
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        shipsInfo % 船舶的AIS信息 顺序同表格的表头
        refer     % 经纬度与坐标转换的相对位置（经纬度）
        mShipsInfo% 投影后经度纬度坐标的转换
        dd          % 责任和方向，两个布尔值，1 1  有责任，右转
        encounter   % 会遇局面，字符串
    end
    
    methods (Access = private)
        
      
        function appPaintShip(app)
            app.UIAxes.NextPlot = "replacechildren";
            colorA = [192,80,70]/255;
            colorB = [75,172,198]/255;
            
            paintShip(app.UIAxes, app.shipsInfo(1, :), colorA, 0);
            paintShip(app.UIAxes, app.shipsInfo(2, :), colorB, 1);


        end
        
        function stateClear(app)
            app.meetingState.Text = "";
            app.turning.Text = "";
            app.duty.Text = "";
        end
        
        function updateJudge(app)
            if app.dd(1) == 1
                app.duty.Text = "有";
                if app.dd(2) == 1
                    app.turning.Text = "右转";
                else
                    app.turning.Text = "左转";
                end
            else
                app.duty.Text = "无";
                app.turning.Text = "";
            end
            
            app.meetingState.Text = app.encounter;
            
        end


        function update(app)
            app.OXSlider.Value = app.shipsInfo(1, 2);
            app.OYSlider.Value = app.shipsInfo(1, 3);
            app.TXSlider.Value = app.shipsInfo(2, 2);
            app.TYSlider.Value = app.shipsInfo(2, 3);
            app.OCOR.Value = app.shipsInfo(1, 4);
            app.TCOR.Value = app.shipsInfo(2, 4);
            app.appPaintShip;
            [app.dd, app.encounter] = colregsMatlab(app.shipsInfo); % dd 责任和方向，encounter 会遇局面
            app.updateJudge;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: inputButton
        function inputButtonPushed(app, event)
            % app.shipsInfo = [413762187, 116.3483, 29.8408, 243.6, 4.2, 96, 15, 243.6; ...
            %         413826629, 116.3495, 29.8403, 239.7569, 4.0354, 110, 20, 239.7569];


            app.shipsInfo = [413762187, 116.3436, 29.8387, 239.4, 4.4, 96, 15, 239.4; ...
                    413815879, 116.3387, 29.8354, 236.7, 3.7, 59, 11, 236.7];
            
%             app.shipsInfo = [413773475	116.2580	29.7855	73.1	5.5	110	19	73.1
% 413839828	116.2541	29.7810	58.4	7.8	130	16	36.8
% ];
           
            % 向表格中添加数据
            table = array2table(app.shipsInfo);
            app.UITable.Data = table;
           
            % 更改位置表示方法
            app.refer = zeros(1, 2);
            app.refer(1) = min(app.shipsInfo(:, 2))-0.01;
            app.refer(2) = min(app.shipsInfo(:, 3))-0.01;
            
            p1 = distanceT(app.refer, app.shipsInfo(1, 2: 3));
            p2 = distanceT(app.refer, app.shipsInfo(2, 2: 3));
            app.shipsInfo(1, 2:3) = p1;
            app.shipsInfo(2, 2:3) = p2;


            % paintInit
            app.UIAxes.DataAspectRatio = [1 1 1]; % 现实比例1：1
            app.UIAxes.NextPlot = "replacechildren";
            xl = min(app.shipsInfo(:, 2))-200;
            xr = max(app.shipsInfo(:, 2)) +200;
            yl = min(app.shipsInfo(:, 3))-200;
            yr = max(app.shipsInfo(:, 3))+200;
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.XLim = [xl, xr];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.YLim = [yl, yr];

            
            app.appPaintShip;
            app.stateClear;


        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            app.update;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            tmpShipsInfo =table2array(app.UITable.Data);
            if indices(2) == 2 || indices(2) ==  3
                tmpShipsInfo(indices(1), indices(2)) = newData;
                p1 = distanceT(app.refer, tmpShipsInfo(1, 2: 3));
                p2 = distanceT(app.refer, tmpShipsInfo(2, 2: 3));
                app.shipsInfo(1, 2:3) = p1;
                app.shipsInfo(2, 2:3) = p2;
            else
                app.shipsInfo(indices(1), indices(2)) = newData;
            end
            
            app.update;
            
        end

        % Value changing function: OXSlider
        function OXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.shipsInfo(1, 2) = changingValue;
            ll = distanceTinv(app.refer, app.shipsInfo(1, 2:3));
            array =table2array(app.UITable.Data);
            array(1, 2:3) = ll;
            table = array2table(array);
            app.UITable.Data = table;
            app.update;
            
        end

        % Value changing function: OYSlider
        function OYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.shipsInfo(1, 3) = changingValue;
            ll = distanceTinv(app.refer, app.shipsInfo(1, 2:3));
            array =table2array(app.UITable.Data);
            array(1, 2:3) = ll;
            table = array2table(array);
            app.UITable.Data = table;
            app.update;
        end

        % Value changing function: TYSlider
        function TYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.shipsInfo(2, 3) = changingValue;
            ll = distanceTinv(app.refer, app.shipsInfo(2, 2:3));
            array =table2array(app.UITable.Data);
            array(2, 2:3) = ll;
            table = array2table(array);
            app.UITable.Data = table;
            app.update;
        end

        % Value changing function: TXSlider
        function TXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.shipsInfo(2, 2) = changingValue;
            ll = distanceTinv(app.refer, app.shipsInfo(2, 2:3));
            array =table2array(app.UITable.Data);
            array(2, 2:3) = ll;
            table = array2table(array);
            app.UITable.Data = table;
            app.update;
            
        end

        % Value changing function: OCOR
        function OCORValueChanging(app, event)
            changingValue = event.Value;
            app.shipsInfo(1, 4) =  changingValue;
            app.shipsInfo(1, 8) = changingValue;
            tmpa = table2array(app.UITable.Data);
            tmpa(1, 4) = changingValue;
            tmpa(1, 8) = changingValue;
            app.UITable.Data = array2table(tmpa);
            app.update;
        end

        % Value changing function: TCOR
        function TCORValueChanging(app, event)
            changingValue = event.Value;
            app.shipsInfo(2, 4) =  changingValue;
            app.shipsInfo(2, 8) = changingValue;
            tmpa = table2array(app.UITable.Data);
            tmpa(2, 4) = changingValue;
            tmpa(2, 8) = changingValue;
            app.UITable.Data = array2table(tmpa);
            app.update;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '示意图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'MMSI'; 'Lon（度）'; 'Lat（度）'; 'Cor（度）'; 'Spe（节）'; 'Len（米）'; 'Wid（米）'; 'Phi（度）'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.inputButton = uibutton(app.UIFigure, 'push');
            app.inputButton.ButtonPushedFcn = createCallbackFcn(app, @inputButtonPushed, true);
            app.inputButton.Text = 'input';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '会遇状态：';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '避碰责任';
            app.meetingState = uilabel(app.UIFigure);
            app.meetingState.Text = '交叉';
            app.duty = uilabel(app.UIFigure);
            app.duty.Text = '有';
            app.turning = uilabel(app.UIFigure);
            app.turning.Text = '左转';
            app.OXSliderLabel = uilabel(app.UIFigure);
            app.OXSliderLabel.Text = 'OX';
            app.OXSlider = uislider(app.UIFigure);
            app.OXSlider.Limits = [800 1600];
            app.OXSlider.ValueChangingFcn = createCallbackFcn(app, @OXSliderValueChanging, true);
            app.OXSlider.Value = 800;
            app.OYSliderLabel = uilabel(app.UIFigure);
            app.OYSliderLabel.Text = 'OY';
            app.OYSlider = uislider(app.UIFigure);
            app.OYSlider.Limits = [900 1700];
            app.OYSlider.ValueChangingFcn = createCallbackFcn(app, @OYSliderValueChanging, true);
            app.OYSlider.Value = 1000;
            app.TXSliderLabel = uilabel(app.UIFigure);
            app.TXSliderLabel.Text = 'TX';
            app.TXSlider = uislider(app.UIFigure);
            app.TXSlider.Limits = [800 1600];
            app.TXSlider.ValueChangingFcn = createCallbackFcn(app, @TXSliderValueChanging, true);
            app.TXSlider.Value = 800;
            app.TYSliderLabel = uilabel(app.UIFigure);
            app.TYSliderLabel.Text = 'TY';
            app.TYSlider = uislider(app.UIFigure);
            app.TYSlider.Limits = [900 1700];
            app.TYSlider.ValueChangingFcn = createCallbackFcn(app, @TYSliderValueChanging, true);
            app.TYSlider.Value = 1000;
            app.OSLabel = uilabel(app.UIFigure);
            app.OSLabel.Text = 'OS';
            app.OCOR = uiknob(app.UIFigure, 'continuous');
            app.OCOR.Limits = [0 360];
            app.OCOR.ValueChangingFcn = createCallbackFcn(app, @OCORValueChanging, true);
            app.TSLabel = uilabel(app.UIFigure);
            app.TSLabel.Text = 'TS';
            app.TCOR = uiknob(app.UIFigure, 'continuous');
            app.TCOR.Limits = [0 360];
            app.TCOR.ValueChangingFcn = createCallbackFcn(app, @TCORValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end